classdef vizapp_4link < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        Tab_1           matlab.ui.container.Tab
        ZSlider_1       matlab.ui.control.Slider
        ZSliderLabel    matlab.ui.control.Label
        YSlider_1       matlab.ui.control.Slider
        YSliderLabel    matlab.ui.control.Label
        XSlider_1       matlab.ui.control.Slider
        XSliderLabel    matlab.ui.control.Label
        Tab_2           matlab.ui.container.Tab
        ZSlider_2       matlab.ui.control.Slider
        ZSlider_2Label  matlab.ui.control.Label
        YSlider_2       matlab.ui.control.Slider
        YSlider_2Label  matlab.ui.control.Label
        XSlider_2       matlab.ui.control.Slider
        XSlider_2Label  matlab.ui.control.Label
        Tab_3           matlab.ui.container.Tab
        ZSlider_3       matlab.ui.control.Slider
        ZSlider_3Label  matlab.ui.control.Label
        YSlider_3       matlab.ui.control.Slider
        YSlider_3Label  matlab.ui.control.Label
        XSlider_3       matlab.ui.control.Slider
        XSlider_3Label  matlab.ui.control.Label
        Tab_4           matlab.ui.container.Tab
        ZSlider_4       matlab.ui.control.Slider
        ZSlider_4Label  matlab.ui.control.Label
        YSlider_4       matlab.ui.control.Slider
        YSlider_4Label  matlab.ui.control.Label
        XSlider_4       matlab.ui.control.Slider
        XSlider_4Label  matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        l1
        width1
        func_seg1_corners
        theta1_vec
        p1_corners

        l2
        width2
        func_seg2_corners
        theta2_vec
        p2_corners

        l3
        width3
        func_seg3_corners
        theta3_vec
        p3_corners

        l4
        width4
        func_seg4_corners
        theta4_vec
        p4_corners

    end
    
    methods (Access = private)
        
        function update_points(app)
            xyz1_corners = app.func_seg1_corners( ...
                app.l1, app.theta1_vec(1), app.theta1_vec(2), app.theta1_vec(3), app.width1 ...
                );

            app.p1_corners.XData = xyz1_corners(:,:,1);
            app.p1_corners.YData = xyz1_corners(:,:,2);
            app.p1_corners.ZData = xyz1_corners(:,:,3);

            xyz2_corners = app.func_seg2_corners( ...
                app.l1, app.theta1_vec(1), app.theta1_vec(2), app.theta1_vec(3), app.width1, ...
                app.l2, app.theta2_vec(1), app.theta2_vec(2), app.theta2_vec(3), app.width2 ...
                );

            app.p2_corners.XData = xyz2_corners(:,:,1);
            app.p2_corners.YData = xyz2_corners(:,:,2);
            app.p2_corners.ZData = xyz2_corners(:,:,3);

            xyz3_corners = app.func_seg3_corners( ...
                app.l1, app.theta1_vec(1), app.theta1_vec(2), app.theta1_vec(3), app.width1, ...
                app.l2, app.theta2_vec(1), app.theta2_vec(2), app.theta2_vec(3), app.width2, ...
                app.l3, app.theta3_vec(1), app.theta3_vec(2), app.theta3_vec(3), app.width3 ...
                );

            app.p3_corners.XData = xyz3_corners(:,:,1);
            app.p3_corners.YData = xyz3_corners(:,:,2);
            app.p3_corners.ZData = xyz3_corners(:,:,3);

            xyz4_corners = app.func_seg4_corners( ...
                app.l1, app.theta1_vec(1), app.theta1_vec(2), app.theta1_vec(3), app.width1, ...
                app.l2, app.theta2_vec(1), app.theta2_vec(2), app.theta2_vec(3), app.width2, ...
                app.l3, app.theta3_vec(1), app.theta3_vec(2), app.theta3_vec(3), app.width3, ...
                app.l4, app.theta4_vec(1), app.theta4_vec(2), app.theta4_vec(3), app.width4 ...
                );

            app.p4_corners.XData = xyz4_corners(:,:,1);
            app.p4_corners.YData = xyz4_corners(:,:,2);
            app.p4_corners.ZData = xyz4_corners(:,:,3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, l1, width1, func_seg1_corners, l2, width2, func_seg2_corners, l3, width3, func_seg3_corners, l4, width4, func_seg4_corners)
            app.l1 = l1;
            app.width1 = width1;
            app.func_seg1_corners = func_seg1_corners;
            app.theta1_vec = [0, 0, 0];

            xyz1_corners = app.func_seg1_corners( ...
                app.l1, app.theta1_vec(1), app.theta1_vec(2), app.theta1_vec(3), app.width1 ...
                );
            app.p1_corners = plot3(app.UIAxes, xyz1_corners(:, :, 1), xyz1_corners(:, :, 2), xyz1_corners(:, :, 3), '-ro');

            hold( app.UIAxes, "on")

            %%
            app.l2 = l2;
            app.width2 = width2;
            app.func_seg2_corners = func_seg2_corners;
            app.theta2_vec = [0, 0, 0];

            xyz2_corners = app.func_seg2_corners( ...
                app.l1, app.theta1_vec(1), app.theta1_vec(2), app.theta1_vec(3), app.width1, ...
                app.l2, app.theta2_vec(1), app.theta2_vec(2), app.theta2_vec(3), app.width2 ...
                );
            app.p2_corners = plot3(app.UIAxes, xyz2_corners(:, :, 1), xyz2_corners(:, :, 2), xyz2_corners(:, :, 3), '-ro');

            %%
            app.l3 = l3;
            app.width3 = width3;
            app.func_seg3_corners = func_seg3_corners;
            app.theta3_vec = [0, 0, 0];

            xyz3_corners = app.func_seg3_corners( ...
                app.l1, app.theta1_vec(1), app.theta1_vec(2), app.theta1_vec(3), app.width1, ...
                app.l2, app.theta2_vec(1), app.theta2_vec(2), app.theta2_vec(3), app.width2, ...
                app.l3, app.theta3_vec(1), app.theta3_vec(2), app.theta3_vec(3), app.width3 ...
                );
            app.p3_corners = plot3(app.UIAxes, xyz3_corners(:, :, 1), xyz3_corners(:, :, 2), xyz3_corners(:, :, 3), '-ro');


            %%
            app.l4 = l4;
            app.width4 = width4;
            app.func_seg4_corners = func_seg4_corners;
            app.theta4_vec = [0, 0, 0];

            xyz4_corners = app.func_seg4_corners( ...
                app.l1, app.theta1_vec(1), app.theta1_vec(2), app.theta1_vec(3), app.width1, ...
                app.l2, app.theta2_vec(1), app.theta2_vec(2), app.theta2_vec(3), app.width2, ...
                app.l3, app.theta3_vec(1), app.theta3_vec(2), app.theta3_vec(3), app.width3, ...
                app.l4, app.theta4_vec(1), app.theta4_vec(2), app.theta4_vec(3), app.width4 ...
                );
            app.p4_corners = plot3(app.UIAxes, xyz4_corners(:, :, 1), xyz4_corners(:, :, 2), xyz4_corners(:, :, 3), '-ro');


            %%
            hold( app.UIAxes, "off")

            range = [-1.1, 1.1] * ( app.l1 + app.l2 + app.l3 + app.l4 );
            app.UIAxes.XLim = range;
            app.UIAxes.YLim = range;
            app.UIAxes.ZLim = range;

            update_points(app)

            daspect(app.UIAxes, [1,1,1])
        end

        % Value changing function: XSlider_1
        function XSlider_1ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta1_vec(1) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: YSlider_1
        function YSlider_1ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta1_vec(2) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: ZSlider_1
        function ZSlider_1ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta1_vec(3) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: XSlider_2
        function XSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta2_vec(1) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: YSlider_2
        function YSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta2_vec(2) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: ZSlider_2
        function ZSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta2_vec(3) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: XSlider_3
        function XSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta3_vec(1) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: YSlider_3
        function YSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta3_vec(2) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: ZSlider_3
        function ZSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta3_vec(3) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: XSlider_4
        function XSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta4_vec(1) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: YSlider_4
        function YSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta4_vec(2) = deg2rad( changingValue );

            update_points(app)
        end

        % Value changing function: ZSlider_4
        function ZSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            
            app.theta4_vec(3) = deg2rad( changingValue );

            update_points(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '1';
            app.XSliderLabel = uilabel(app.Tab_1);
            app.XSliderLabel.Text = 'X';
            app.XSlider_1 = uislider(app.Tab_1);
            app.XSlider_1.Limits = [-180 180];
            app.XSlider_1.ValueChangingFcn = createCallbackFcn(app, @XSlider_1ValueChanging, true);
            app.YSliderLabel = uilabel(app.Tab_1);
            app.YSliderLabel.Text = 'Y';
            app.YSlider_1 = uislider(app.Tab_1);
            app.YSlider_1.Limits = [-180 180];
            app.YSlider_1.ValueChangingFcn = createCallbackFcn(app, @YSlider_1ValueChanging, true);
            app.ZSliderLabel = uilabel(app.Tab_1);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider_1 = uislider(app.Tab_1);
            app.ZSlider_1.Limits = [-180 180];
            app.ZSlider_1.ValueChangingFcn = createCallbackFcn(app, @ZSlider_1ValueChanging, true);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '2';
            app.XSlider_2Label = uilabel(app.Tab_2);
            app.XSlider_2Label.Text = 'X';
            app.XSlider_2 = uislider(app.Tab_2);
            app.XSlider_2.Limits = [-180 180];
            app.XSlider_2.ValueChangingFcn = createCallbackFcn(app, @XSlider_2ValueChanging, true);
            app.YSlider_2Label = uilabel(app.Tab_2);
            app.YSlider_2Label.Text = 'Y';
            app.YSlider_2 = uislider(app.Tab_2);
            app.YSlider_2.Limits = [-180 180];
            app.YSlider_2.ValueChangingFcn = createCallbackFcn(app, @YSlider_2ValueChanging, true);
            app.ZSlider_2Label = uilabel(app.Tab_2);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSlider_2 = uislider(app.Tab_2);
            app.ZSlider_2.Limits = [-180 180];
            app.ZSlider_2.ValueChangingFcn = createCallbackFcn(app, @ZSlider_2ValueChanging, true);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '3';
            app.XSlider_3Label = uilabel(app.Tab_3);
            app.XSlider_3Label.Text = 'X';
            app.XSlider_3 = uislider(app.Tab_3);
            app.XSlider_3.Limits = [-180 180];
            app.XSlider_3.ValueChangingFcn = createCallbackFcn(app, @XSlider_3ValueChanging, true);
            app.YSlider_3Label = uilabel(app.Tab_3);
            app.YSlider_3Label.Text = 'Y';
            app.YSlider_3 = uislider(app.Tab_3);
            app.YSlider_3.Limits = [-180 180];
            app.YSlider_3.ValueChangingFcn = createCallbackFcn(app, @YSlider_3ValueChanging, true);
            app.ZSlider_3Label = uilabel(app.Tab_3);
            app.ZSlider_3Label.Text = 'Z';
            app.ZSlider_3 = uislider(app.Tab_3);
            app.ZSlider_3.Limits = [-180 180];
            app.ZSlider_3.ValueChangingFcn = createCallbackFcn(app, @ZSlider_3ValueChanging, true);
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '4';
            app.XSlider_4Label = uilabel(app.Tab_4);
            app.XSlider_4Label.Text = 'X';
            app.XSlider_4 = uislider(app.Tab_4);
            app.XSlider_4.Limits = [-180 180];
            app.XSlider_4.ValueChangingFcn = createCallbackFcn(app, @XSlider_4ValueChanging, true);
            app.YSlider_4Label = uilabel(app.Tab_4);
            app.YSlider_4Label.Text = 'Y';
            app.YSlider_4 = uislider(app.Tab_4);
            app.YSlider_4.Limits = [-180 180];
            app.YSlider_4.ValueChangingFcn = createCallbackFcn(app, @YSlider_4ValueChanging, true);
            app.ZSlider_4Label = uilabel(app.Tab_4);
            app.ZSlider_4Label.Text = 'Z';
            app.ZSlider_4 = uislider(app.Tab_4);
            app.ZSlider_4.Limits = [-180 180];
            app.ZSlider_4.ValueChangingFcn = createCallbackFcn(app, @ZSlider_4ValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vizapp_4link(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end